classdef Ejemplo2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PathLossUIFigure             matlab.ui.Figure
        PlotButton                   matlab.ui.control.Button
        SwitchLabel                  matlab.ui.control.Label
        Switch                       matlab.ui.control.Switch
        LampLabel                    matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        TabGroup                     matlab.ui.container.TabGroup
        RealTab                      matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        NormalizadoTab               matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        Diffraction_LossTab          matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        PathLossTab                  matlab.ui.container.Tab
        UIAxes4                      matlab.ui.control.UIAxes
        SpectrumTab                  matlab.ui.container.Tab
        UIAxes6                      matlab.ui.control.UIAxes
        OFDMTab                      matlab.ui.container.Tab
        UIAxes5                      matlab.ui.control.UIAxes
        ModulacionesTab              matlab.ui.container.Tab
        UIAxes7                      matlab.ui.control.UIAxes
        SpectrumPanel                matlab.ui.container.Panel
        FsMHzEditFieldLabel          matlab.ui.control.Label
        FsMHzEditField               matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel       matlab.ui.control.Label
        AmplitudEditField            matlab.ui.control.NumericEditField
        OFDMPanel                    matlab.ui.container.Panel
        cpLenEditFieldLabel          matlab.ui.control.Label
        cpLenEditField               matlab.ui.control.NumericEditField
        bitsPerSubCarrierEditFieldLabel  matlab.ui.control.Label
        bitsPerSubCarrierEditField   matlab.ui.control.NumericEditField
        numRBsDropDownLabel          matlab.ui.control.Label
        numRBsDropDown               matlab.ui.control.DropDown
        PathLossPanel                matlab.ui.container.Panel
        DropDown_2Label              matlab.ui.control.Label
        DropDown_2                   matlab.ui.control.DropDown
        d0EditFieldLabel             matlab.ui.control.Label
        d0EditField                  matlab.ui.control.NumericEditField
        nEditFieldLabel              matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        sigmaEditFieldLabel          matlab.ui.control.Label
        sigmaEditField               matlab.ui.control.NumericEditField
        ModelosdeRayosPanel          matlab.ui.container.Panel
        DropDownLabel                matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        wEditFieldLabel              matlab.ui.control.Label
        wEditField                   matlab.ui.control.NumericEditField
        R1EditFieldLabel             matlab.ui.control.Label
        R1EditField                  matlab.ui.control.NumericEditField
        REditFieldLabel              matlab.ui.control.Label
        REditField                   matlab.ui.control.NumericEditField
        ParametrosgeneralesPanel     matlab.ui.container.Panel
        FrecuenciaMHzEditFieldLabel  matlab.ui.control.Label
        FrecuenciaMHzEditField       matlab.ui.control.NumericEditField
        DistanciaKmEditFieldLabel    matlab.ui.control.Label
        DistanciaKmEditField         matlab.ui.control.NumericEditField
        GtEditFieldLabel             matlab.ui.control.Label
        GtEditField                  matlab.ui.control.NumericEditField
        GrEditFieldLabel             matlab.ui.control.Label
        GrEditField                  matlab.ui.control.NumericEditField
        htmEditFieldLabel            matlab.ui.control.Label
        htmEditField                 matlab.ui.control.NumericEditField
        hrmEditFieldLabel            matlab.ui.control.Label
        hrmEditField                 matlab.ui.control.NumericEditField
        ModulacionesPanel            matlab.ui.container.Panel
        TipoDropDownLabel            matlab.ui.control.Label
        TipoDropDown                 matlab.ui.control.DropDown
    end

 properties (Access = private)
        control;
        tipoEfecto;
        valor_PL;
        tipoMod;
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            distancia = app.DistanciaKmEditField.Value;
            f = app.FrecuenciaMHzEditField.Value;
            ht = app.htmEditField.Value;
            hr = app.hrmEditField.Value;
            
            
            P = efectosCanal(app,distancia,f,ht,hr);
            L = pathLoss(app,distancia,f,ht,hr); 
            plotEfectosCanal(app,P,distancia,f,ht,hr);
            plotPL(app, L, distancia);
            spectrumAnalyzar(app,L,f);
            OFDM(app,L);
            difraccion(app);
            X = modulacion(app);
            plotModulacion(app,X);
           
     end
        
        function result = efectosCanal(app,distancia,f,ht,hr)
            
            Gt = app.GtEditField.Value;
            Gr = app.GrEditField.Value;
            R = app.REditField.Value;
            R1 = app.R1EditField.Value;
            w = app.wEditField.Value;
            
            distancia_metros = distancia*1e3;
            f_Hz = f*1e6;
            
            lambda = 3e8/f_Hz;
            d = 1:1:distancia_metros;
            
            l = sqrt((ht-hr)^2+d.^2);
            r = sqrt((ht+hr)^2+d.^2);
            phi = 2*pi*(r-l)/lambda;
            
            l_1 = sqrt((ht-hr)^2 + d.^2 + (2*w)^2);
            r_1 = sqrt((ht+hr)^2 + d.^2 + (2*w)^2);
            l_2 = sqrt((ht-hr)^2 + d.^2 + (2*w*2)^2); %10
            r_2 = sqrt((ht+hr)^2 + d.^2 + (2*w*2)^2); %10
            l_3 = sqrt((ht-hr)^2 + d.^2 + (2*w*4)^2); %1
            r_3 = sqrt((ht+hr)^2 + d.^2 + (2*w*4)^2); %1
            l_4 = sqrt((ht-hr)^2 + d.^2 + (2*w*6)^2); %20
            r_4 = sqrt((ht+hr)^2 + d.^2 + (2*w*6)^2); %20


            phi_l_1= 2*pi*l_1/lambda;
            phi_r_1 = 2*pi*r_1/lambda;
            phi_l_2= 2*pi*l_2/lambda;
            phi_r_2 = 2*pi*r_2/lambda;
            phi_l_3= 2*pi*l_3/lambda;
            phi_r_3 = 2*pi*r_3/lambda;
            phi_l_4= 2*pi*l_4/lambda;
            phi_r_4 = 2*pi*r_4/lambda;
            
            Pr_21 = ((sqrt(Gt*Gr).*lambda.*R1)/(4*pi).*abs(exp(-1j.*phi_l_1)./l_1 + R*exp(-1j.*phi_r_1)./r_1));
            Pr_22 = ((sqrt(Gt*Gr).*lambda.*-R1)/(4*pi).*abs(exp(-1j.*phi_l_2)./l_2 + R*exp(-1j.*phi_r_2)./r_2));
            Pr_23 = ((sqrt(Gt*Gr).*lambda.*R1)/(4*pi).*abs(exp(-1j.*phi_l_3)./l_3 + R*exp(-1j.*phi_r_3)./r_3));
            Pr_24 = ((sqrt(Gt*Gr).*lambda.*-R1)/(4*pi).*abs(exp(-1j.*phi_l_4)./l_4 + R*exp(-1j.*phi_r_4)./r_4));
            
            switch app.DropDown.Value
                case 'Free-Space Path Loss'
                    P = (lambda./(4*pi*d)).^2;
                    P_abs = P;
                    app.tipoEfecto = 1;
                case 'Two-Ray Model'
                    Vec = sqrt(Gt)./l + R*sqrt(Gr)*exp(-1j.*phi)./r;
                    P = abs(lambda.*(Vec)/4*pi).^2;
                    P_abs = abs(P);
                    app.tipoEfecto = 2;
                case 'Six-Ray Model'
                    Vec = sqrt(Gt)./l + R*sqrt(Gr)*exp(-1j.*phi)./r;
                    Pr = abs(lambda.*(Vec)/4*pi).^2;
                    P = Pr + Pr_21 + Pr_22;
                    P_abs = abs(P);
                    app.tipoEfecto = 3;
                case 'Ten-Ray Model'  
                    Vec = sqrt(Gt)./l + R*sqrt(Gr)*exp(-1j.*phi)./r;
                    Pr = abs(lambda.*(Vec)/4*pi).^2;
                    P = Pr + Pr_21 + Pr_22 + Pr_23 + Pr_24;
                    P_abs = abs(P);
                    app.tipoEfecto = 4;
                case 'All'
                    P1 = (lambda./(4*pi*d)).^2;
                    P1_abs = P1;
                    
                    Vec = sqrt(Gt)./l + R*sqrt(Gr)*exp(-1j.*phi)./r;
                    P2 = abs(lambda.*(Vec)/4*pi).^2;
                    P2_abs = abs(P2);
                    
                    P3 = P2 + Pr_21 + Pr_22;
                    P3_abs = abs(P3);
                    
                    P4 = P2 + Pr_21 + Pr_22 + Pr_23 + Pr_24;
                    P4_abs = abs(P4);
                    
                    P_abs = {P1_abs,P2_abs,P3_abs,P4_abs};
                    app.tipoEfecto = 5;
                    
            end
            result = P_abs;
        end
        
        function result = pathLoss(app,distancia,f,ht,hr)
            
            d = 10:1:distancia;
            Gt = app.GtEditField.Value;
            Gr = app.GrEditField.Value;
            d0 = app.d0EditField.Value;
            n = app.nEditField.Value;
            sigma = app.sigmaEditField.Value;
            
            f_Hz = f*1e6;
            lambda = 3e8/f_Hz;
            
            if f >= 150 && f<= 200
                a = 8.29*(log10(1.54*hr))^2 - 1.1;
            elseif f > 200
                a = 3.2*(log10(11.75*hr))^2 - 4.97;
            else
                a = 0.8+(1.1*log10(f)-0.7)*hr-1.56*log10(f);
            end
            
            L = 69.55 + 26.16*log10(f) - 13.82*log10(ht) - a + (44.9 - 6.55*log10(ht))*log10(d);
            
            switch app.DropDown_2.Value
                case 'Friis'
                    L_L = 32.45 + 20*log10(f) + 20*log10(d);
                    app.valor_PL = 1;
                    
                case 'Okumura-Hata Urbano'
                    %a = (1.1*log10(f) - 0.7)*ht - (1.56*log10(f) - 0.8);
                    L_L = L;
                    app.valor_PL = 2;
                    
                case 'Okumura-Hata Suburbano'
                    %a = 8.29*(log10(1.54*ht)^2) - 1.1;
                    L_L = L -2*(log10(f/28))^2 -5.4;
                    app.valor_PL = 3;
                    
                case 'Okumura-Hata Abierto'
                    %a = 3.2*(log10(11.45*ht)^2) - 4.97;
                    L_L = L + (18.33-4.78*log10(f))*log10(f)-40.97;
                    app.valor_PL = 4;
                    
                case 'Espacio Libre'
                    L_L = -20*log10(sqrt(Gt)*sqrt(Gr)*lambda./(4*pi*d));
                    app.valor_PL = 5;
                    
                case 'Log-distancia'
                    L_L = -20*log10(lambda/(4*pi*d0))+10*n*log10(d/d0);
                    app.valor_PL = 6;
                    
                case 'Log-normal'
                    L_L = -20*log10(lambda/(4*pi*d0))+10*n*log10(d/d0) + sigma*randn(size(d));
                    app.valor_PL = 7;
                    
                case 'All'
                    L1 = 32.45 + 20*log10(f) + 20*log10(d);
                    L2 = L;
                    L3 = L -2*(log10(f/28))^2 -5.4;
                    L4 = L + (18.33-4.78*log10(f))*log10(f)-40.97;
                    L5 = -20*log10(sqrt(Gt)*sqrt(Gr)*lambda./(4*pi*d));
                    L6 = -20*log10(lambda/(4*pi*d0))+10*n*log10(d/d0);
                    L7 = -20*log10(lambda/(4*pi*d0))+10*n*log10(d/d0) + sigma*randn(size(d));
                    L_L = {L1,L2,L3,L4,L5,L6,L7};
                    app.valor_PL = 8;
            end
            result = L_L;            
        end
        
        function spectrumAnalyzar(app,L,f)
            fs = app.FsMHzEditField.Value;
            A = app.AmplitudEditField.Value;
            Ts = 1/fs;
            t = 0:Ts:1-Ts; 
            x = A*sin(2*pi*f*t);
            p = pspectrum(x,fs);
            f_c = 0:(fs/2)/4095:fs/2;
            if ~strcmp(app.DropDown_2.Value, 'All')
                plot(app.UIAxes6, f_c, 10*log10(p/10^-3),'blue',f_c, 10*log10(p/10^-3) - L,'red');
                legend(app.UIAxes6,'Potencia Transmitida','Potencia Recibida','Location','northwest');
            end
        end
        
        function OFDM(app,L)
            numRBs = str2double(app.numRBsDropDown.Value); % Número de bloques de recursos
            cpLen = app.cpLenEditField.Value; % Longitud del prefijo cíclico
            bitsPerSubCarrier = app.bitsPerSubCarrierEditField.Value; % 2: QPSK, 4: 16QAM, 6: 64QAM, 8: 256QAM
            rbSize = 12; %  Número de subportadoras por bloque de recursos
            numFFT = 1024; % Longitud FFT
            numDataCarriers = numRBs*rbSize;  % Número de subportadoras de datos en la sub-banda
            bitsIn = randi([0 1], bitsPerSubCarrier*numDataCarriers, 1); % Generar símbolos de datos
            symbolsIn = qammod(bitsIn, 2^bitsPerSubCarrier, 'InputType', 'bit', 'UnitAveragePower', true); % Mapear los símbolos
            % Datos introducidos en los simbolos OFDM
            offset = (numFFT-numDataCarriers)/2;
            symbolsInOFDM = [zeros(offset,1); symbolsIn;zeros(numFFT-offset-numDataCarriers,1)];
            ifftOut = ifft(ifftshift(symbolsInOFDM));
            % Prefijo ciclico
            txSigOFDM = [ifftOut(end-cpLen+1:end); ifftOut];
            % Representar la densidad espectral de potencia (PSD)
            [psd,f_Hz] = periodogram(txSigOFDM, rectwin(length(txSigOFDM)), numFFT*2, 1, 'centered');
            if  ~strcmp(app.DropDown_2.Value, 'All')
                plot(app.UIAxes5,f_Hz,10*log10(psd),'blue',f_Hz,10*log10(psd)-L,'red');
                title(app.UIAxes5,['OFDM, ' num2str(numRBs*rbSize) ' Subcarriers']);
                legend(app.UIAxes5,'Tx','Rx','Location','best');
            end   
        end
        
        function difraccion(app)
                v = -5:0.01:5;
                
                for n=1:length(v)

                    if v(n) < -0.8
                        G(n)=0;
                    elseif v(n) >= -0.8 && v(n) < 0
                        G(n)=20*log10(0.5-0.62*v(n));
                    elseif v(n) >= 0 && v(n) < 1
                        G(n)=20*log10(0.5*exp(-0.95*v(n)));
                    elseif v(n) >= 1 && v(n) < 2.4
                        G(n)=20*log10(0.4-sqrt(0.1184-(0.38-0.1*v(n))^2));
                    else
                        G(n)=20*log10(0.225/v(n));
                    end
    
                end
                plot(app.UIAxes3, v, G, 'blue');
        end

        function  plotPL(app, L, distancia)
                
            d_km = 10:1:distancia;
        
                if app.valor_PL == 1
                    semilogx(app.UIAxes4, d_km, L, 'blue');
                    legend(app.UIAxes4,'Friis', 'Location','northwest');
                end
                
                if app.valor_PL == 2
                    semilogx(app.UIAxes4, d_km, L, 'red');
                    legend(app.UIAxes4,'Okumura-Hata Urbano', 'Location','northwest');
                end
                
                if app.valor_PL == 3
                    semilogx(app.UIAxes4, d_km, L, 'green');
                    legend(app.UIAxes4,'Okumura-Hata Suburbano', 'Location','northwest');
                end
                
                if app.valor_PL == 4
                    semilogx(app.UIAxes4, d_km, L, 'magenta');
                    legend(app.UIAxes4,'Okumura-Hata Abierto', 'Location','northwest');
                end
                
                if app.valor_PL == 5
                    semilogx(app.UIAxes4, d_km, L, 'yellow');
                    legend(app.UIAxes4,'Espacio Libre', 'Location','northwest');
                end
                
                if app.valor_PL == 6
                    semilogx(app.UIAxes4, d_km, L, 'cyan');
                    legend(app.UIAxes4,'Log-distancia', 'Location','northwest');
                end
                
                if app.valor_PL == 7
                    semilogx(app.UIAxes4, d_km, L, 'black');
                    legend(app.UIAxes4,'Log-normal', 'Location','northwest');
                end
                
                if app.valor_PL == 8
                    semilogx(app.UIAxes4,d_km,L{:,1},'blue',d_km,L{:,2},'red',...
                        d_km,L{:,3},'green',d_km,L{:,4},'magenta',...
                        d_km, L{:,5}, 'yellow', d_km, L{:,6}, 'cyan', d_km, L{:,7}, 'black');
                    legend(app.UIAxes4,'Friis','Okumura-Hata Urbano','Okumura-Hata Suburbano',...
                        'Okumura-Hata Abierto','Espacio Libre','Log-distancia','Log-normal','Location','northwest');
                end
        end
        
        function plotEfectosCanal(app,P,distancia,f,ht,hr)
        
            distancia_metros = distancia*1e3;
            d = 1:1:distancia_metros;
            
            f_Hz = f*1e6;
            lambda = 3e8/f_Hz;
            
            dc = 4*ht*hr/lambda;
            d1 = 1:1:ht;  
            d2 = ht:1:dc;  
            d3 = dc:1:distancia_metros;
            valor = 1./(d1.^2 + ht.^2);
            correccion = 40*log10(dc) - 20*log10(dc);
            
                 if app.tipoEfecto == 1
                    semilogx(app.UIAxes, d, 10*log10(P)-10*log10(P(1)), 'blue');
                    xline(app.UIAxes,ht,'-.',{'ht'});
                    xline(app.UIAxes,dc,'--',{'dc'});
                    legend(app.UIAxes,{'Free-Space Path Loss'}, 'Location','southwest');
                    
                    semilogx(app.UIAxes2, d, 10*log10(P), 'blue');
                    xline(app.UIAxes2,ht,'-.',{'ht'});
                    xline(app.UIAxes2,dc,'--',{'dc'});
                    legend(app.UIAxes2,'Free-Space Path Loss', 'Location','southwest');
                end
                
                if app.tipoEfecto == 2
                    semilogx(app.UIAxes, d, 10*log10(P)-10*log10(P(1)), 'blue', d1, 10*log10(valor),...
                        'red', d2, -20*log10(d2), 'red', d3, -40*log10(d3)+correccion, 'red');
                    xline(app.UIAxes,ht,'-.',{'ht'});
                    xline(app.UIAxes,dc,'--',{'dc'});
                    legend(app.UIAxes,'Two-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                    
                    semilogx(app.UIAxes2, d, 10*log10(P), 'blue', d1, 10*log10(valor),...
                        'red', d2, -20*log10(d2), 'red', d3, -40*log10(d3)+correccion, 'red');
                    xline(app.UIAxes2,ht,'-.',{'ht'});
                    xline(app.UIAxes2,dc,'--',{'dc'});
                    legend(app.UIAxes2,'Two-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                end
                
                 if app.tipoEfecto == 3
                    semilogx(app.UIAxes, d, 10*log10(P)-10*log10(P(1)), 'blue', d1, 10*log10(valor),...
                        'red', d2, -20*log10(d2), 'red', d3, -40*log10(d3)+correccion, 'red');
                    xline(app.UIAxes,ht,'-.',{'ht'});
                    xline(app.UIAxes,dc,'--',{'dc'});
                    legend(app.UIAxes,'Six-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                    
                    semilogx(app.UIAxes2, d, 10*log10(P), 'blue', d1, 10*log10(valor),...
                        'red', d2, -20*log10(d2), 'red', d3, -40*log10(d3)+correccion, 'red');
                    xline(app.UIAxes2,ht,'-.',{'ht'});
                    xline(app.UIAxes2,dc,'--',{'dc'});
                    legend(app.UIAxes2,'Six-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                 end
                             
                 if app.tipoEfecto == 4
                    semilogx(app.UIAxes, d, 10*log10(P)-10*log10(P(1)), 'blue', d1, 10*log10(valor),...
                        'red', d2, -20*log10(d2), 'red', d3, -40*log10(d3)+correccion, 'red');
                    xline(app.UIAxes,ht,'-.',{'ht'});
                    xline(app.UIAxes,dc,'--',{'dc'});
                    legend(app.UIAxes,'Ten-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                    
                    semilogx(app.UIAxes2, d, 10*log10(P), 'blue', d1, 10*log10(valor),...
                        'red', d2, -20*log10(d2), 'red', d3, -40*log10(d3)+correccion, 'red');
                    xline(app.UIAxes2,ht,'-.',{'ht'});
                    xline(app.UIAxes2,dc,'--',{'dc'});
                    legend(app.UIAxes2,'Ten-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                end
                                                
                if app.tipoEfecto == 5
                    semilogx(app.UIAxes,d,10*log10(P{:,1})-10*log10(P{:,1}(1)),'red',d,10*log10(P{:,2})-10*log10(P{:,2}(1)),'blue',...
                        d,10*log10(P{:,3})-10*log10(P{:,3}(1)),'green',d,10*log10(P{:,4})-10*log10(P{:,4}(1)),'black',...
                        d1, 10*log10(valor), 'magenta', d2, -20*log10(d2), 'magenta', d3, -40*log10(d3)+correccion, 'magenta');
                    xline(app.UIAxes,ht,'-.',{'ht'});
                    xline(app.UIAxes,dc,'--',{'dc'});
                    legend(app.UIAxes,'Free-Space Path Loss','Two-Ray Model Power Falloff','Six-Ray Model Power Falloff',...
                        'Ten-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                    
                    semilogx(app.UIAxes2,d,10*log10(P{:,1}),'red',d,10*log10(P{:,2}),'blue',...
                        d,10*log10(P{:,3}),'green',d,10*log10(P{:,4}),'black',...
                        d1, 10*log10(valor), 'magenta', d2, -20*log10(d2), 'magenta', d3, -40*log10(d3)+correccion, 'magenta');
                    xline(app.UIAxes2,ht,'-.',{'ht'});
                    xline(app.UIAxes2,dc,'--',{'dc'});
                    legend(app.UIAxes2,'Free-Space Path Loss','Two-Ray Model Power Falloff','Six-Ray Model Power Falloff',...
                        'Ten-Ray Model Power Falloff','Piecewise Linear Approximation', 'Location','southwest');
                end
                
        end
        
        function result = modulacion(app)
            switch app.TipoDropDown.Value
                case 'BPSK'
                    mod_order = 1;
                    mod_ind = 2^(mod_order-1);
                    n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                    in_phase = cos(n);
                    quadrature = sin(n);
                    symbol_book = (in_phase + quadrature*1i);
                    app.tipoMod = 1;
                case 'QPSK'
                    mod_order = 2;
                    mod_ind = 2^(mod_order-1);
                    n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                    in_phase = cos(n+pi/4);
                    quadrature = sin(n+pi/4);
                    symbol_book = (in_phase + quadrature*1i);
                    app.tipoMod = 2;
                 case '8PSK'
                    mod_order = 3;
                    mod_ind = 2^(mod_order-1);
                    n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                    in_phase = cos(n+pi/4);
                    quadrature = sin(n+pi/4);
                    symbol_book = (in_phase + quadrature*1i);
                    app.tipoMod = 3;
                 case '16QAM'
                    mod_order = 4;
                    mod_ind = sqrt(2^mod_order);
                    in_phase = repmat(linspace(-1,1,mod_ind),mod_ind,1);
                    quadrature = repmat(linspace(-1,1,mod_ind)',1,mod_ind);
                    symbol_book = (in_phase(:) + quadrature(:)*1i);
                 case '32QAM'
                    mod_ind = 6;
                    in_phase = repmat(linspace(-1,1,mod_ind),mod_ind,1);
                    quadrature = repmat(linspace(-1,1,mod_ind)',1,mod_ind);
                    symbol_book = (in_phase(:) + quadrature(:)*1i);
                    symbol_book = symbol_book([2:5 7:30 32:35]); %corners are removed
                 case '64QAM'
                    mod_order = 6;
                    mod_ind = sqrt(2^mod_order);
                    in_phase = repmat(linspace(-2,2,mod_ind),mod_ind,1);
                    quadrature = repmat(linspace(-2,2,mod_ind)',1,mod_ind);
                    symbol_book = (in_phase(:) + quadrature(:)*1i);
                 case '128QAM'
                    mod_ind = 12;
                    in_phase = repmat(linspace(-2,2,mod_ind),mod_ind,1);
                    quadrature = repmat(linspace(-2,2,mod_ind)',1,mod_ind);
                    symbol_book = (in_phase(:) + quadrature(:)*1i);
                    symbol_book = symbol_book([3:10 15:22 25:120 123:130 135:142]); %corners are removed
                 case '256QAM'
                    mod_order = 8;
                    mod_ind = sqrt(2^mod_order);
                    in_phase = repmat(linspace(-2,2,mod_ind),mod_ind,1);
                    quadrature = repmat(linspace(-2,2,mod_ind)',1,mod_ind);
                    symbol_book = (in_phase(:) + quadrature(:)*1i);
            end
            result = symbol_book;
        end
        
        function plotModulacion(app,X)
        
            if app.tipoMod == 1
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
                xlim(app.UIAxes7,[-3 3]);
                ylim(app.UIAxes7,[-3 3]);
            end
                
            if app.tipoMod == 2
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
            end
            
            if app.tipoMod == 3
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
            end
            
            if app.tipoMod == 4
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
            end
            
            if app.tipoMod == 5
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
            end
            
            if app.tipoMod == 6
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
            end
            
            if app.tipoMod == 7
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
            end
            
            if app.tipoMod == 8
                plot(app.UIAxes7,X,'x','linewidth',2,'markersize',10);
                xlim(app.UIAxes7,[-3 3]);
                ylim(app.UIAxes7,[-3 3]);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Switch.Value = 'on';
            app.PlotButton.Enable = 'off';
            app.control = 1;
            app.valor_PL = 0;
            app.tipoEfecto = 0;
            updateplot(app);
        end

        % Value changed function: AmplitudEditField, 
        % DistanciaKmEditField, DropDown, DropDown_2, 
        % FrecuenciaMHzEditField, FsMHzEditField, GrEditField, 
        % GtEditField, REditField, TipoDropDown, 
        % bitsPerSubCarrierEditField, cpLenEditField, d0EditField, 
        % hrmEditField, htmEditField, nEditField, numRBsDropDown, 
        % sigmaEditField, wEditField
        function ComponentValueChanged(app, event)
            if app.control
                updateplot(app);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updateplot(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if strcmp(app.Switch.Value, 'on')
                app.control = 1 ;
                app.PlotButton.Enable = 'off' ;
                app.Lamp.Color = [0 1 0] ;
            else
                app.control = 0 ;
                app.PlotButton.Enable = 'on' ;
                app.Lamp.Color = [0.5 0.5 0.5] ;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PathLossUIFigure = uifigure('Visible', 'off');
            app.PathLossUIFigure.Name = 'Path Loss';
            app.PlotButton = uibutton(app.PathLossUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SwitchLabel = uilabel(app.PathLossUIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.PathLossUIFigure, 'slider');
            app.Switch.Items = {'off', 'on'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'off';
            app.LampLabel = uilabel(app.PathLossUIFigure);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.PathLossUIFigure);
            app.TabGroup = uitabgroup(app.PathLossUIFigure);
            app.RealTab = uitab(app.TabGroup);
            app.RealTab.Title = 'Real';
            app.UIAxes2 = uiaxes(app.RealTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'log (d) (metros)')
            ylabel(app.UIAxes2, 'Potencia (dB)')
            app.NormalizadoTab = uitab(app.TabGroup);
            app.NormalizadoTab.Title = 'Normalizado';
            app.UIAxes = uiaxes(app.NormalizadoTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'log (d) (metros)')
            ylabel(app.UIAxes, 'Potencia Normalizada (dB)')
            app.Diffraction_LossTab = uitab(app.TabGroup);
            app.Diffraction_LossTab.Title = 'Diffraction_Loss';
            app.UIAxes3 = uiaxes(app.Diffraction_LossTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'Parámetro de difracción de Fresnel')
            ylabel(app.UIAxes3, 'Perdidas por Difraccion (dB)')
            app.PathLossTab = uitab(app.TabGroup);
            app.PathLossTab.Title = 'Path Loss';
            app.UIAxes4 = uiaxes(app.PathLossTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, 'Distancia (Km)')
            ylabel(app.UIAxes4, 'PL (dB)')
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.UIAxes6 = uiaxes(app.SpectrumTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, 'Frecuencia (MHz)')
            ylabel(app.UIAxes6, 'Potencia (dBm)')
            app.OFDMTab = uitab(app.TabGroup);
            app.OFDMTab.Title = 'OFDM';
            app.UIAxes5 = uiaxes(app.OFDMTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, 'Frecuencia Normalizada')
            ylabel(app.UIAxes5, 'PSD (dBW/Hz)')
            app.ModulacionesTab = uitab(app.TabGroup);
            app.ModulacionesTab.Title = 'Modulaciones';
            app.UIAxes7 = uiaxes(app.ModulacionesTab);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, 'I')
            ylabel(app.UIAxes7, 'Q')
            app.SpectrumPanel = uipanel(app.PathLossUIFigure);
            app.SpectrumPanel.Title = 'Spectrum';
            app.FsMHzEditFieldLabel = uilabel(app.SpectrumPanel);
            app.FsMHzEditFieldLabel.Text = 'Fs (MHz)';
            app.FsMHzEditField = uieditfield(app.SpectrumPanel, 'numeric');
            app.FsMHzEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.FsMHzEditField.Value = 2000;
            app.AmplitudEditFieldLabel = uilabel(app.SpectrumPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.SpectrumPanel, 'numeric');
            app.AmplitudEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.AmplitudEditField.Value = 1;
            app.OFDMPanel = uipanel(app.PathLossUIFigure);
            app.OFDMPanel.Title = 'OFDM';
            app.cpLenEditFieldLabel = uilabel(app.OFDMPanel);
            app.cpLenEditFieldLabel.Text = 'cpLen';
            app.cpLenEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.cpLenEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.cpLenEditField.Value = 72;
            app.bitsPerSubCarrierEditFieldLabel = uilabel(app.OFDMPanel);
            app.bitsPerSubCarrierEditFieldLabel.Text = 'bitsPerSubCarrier';
            app.bitsPerSubCarrierEditField = uieditfield(app.OFDMPanel, 'numeric');
            app.bitsPerSubCarrierEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.bitsPerSubCarrierEditField.Value = 6;
            app.numRBsDropDownLabel = uilabel(app.OFDMPanel);
            app.numRBsDropDownLabel.Text = 'numRBs';
            app.numRBsDropDown = uidropdown(app.OFDMPanel);
            app.numRBsDropDown.Items = {'6', '15', '25', '50', '75', '100'};
            app.numRBsDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.numRBsDropDown.Value = '50';
            app.PathLossPanel = uipanel(app.PathLossUIFigure);
            app.PathLossPanel.Title = 'Path Loss';
            app.DropDown_2Label = uilabel(app.PathLossPanel);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown_2 = uidropdown(app.PathLossPanel);
            app.DropDown_2.Items = {'Friis', 'Okumura-Hata Urbano', 'Okumura-Hata Suburbano', 'Okumura-Hata Abierto', 'Espacio Libre', 'Log-distancia', 'Log-normal', 'All'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.DropDown_2.Value = 'Friis';
            app.d0EditFieldLabel = uilabel(app.PathLossPanel);
            app.d0EditFieldLabel.Text = 'd0';
            app.d0EditField = uieditfield(app.PathLossPanel, 'numeric');
            app.d0EditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.d0EditField.Value = 1000;
            app.nEditFieldLabel = uilabel(app.PathLossPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.PathLossPanel, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.nEditField.Value = 4;
            app.sigmaEditFieldLabel = uilabel(app.PathLossPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.PathLossPanel, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.sigmaEditField.Value = 3;
            app.ModelosdeRayosPanel = uipanel(app.PathLossUIFigure);
            app.ModelosdeRayosPanel.Title = 'Modelos de Rayos';
            app.DropDownLabel = uilabel(app.ModelosdeRayosPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.ModelosdeRayosPanel);
            app.DropDown.Items = {'Free-Space Path Loss', 'Two-Ray Model', 'Six-Ray Model', 'Ten-Ray Model', 'All'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.DropDown.Value = 'Free-Space Path Loss';
            app.wEditFieldLabel = uilabel(app.ModelosdeRayosPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.ModelosdeRayosPanel, 'numeric');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.wEditField.Value = 5;
            app.R1EditFieldLabel = uilabel(app.ModelosdeRayosPanel);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.ModelosdeRayosPanel, 'numeric');
            app.R1EditField.Value = 1;
            app.REditFieldLabel = uilabel(app.ModelosdeRayosPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.ModelosdeRayosPanel, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.REditField.Value = -1;
            app.ParametrosgeneralesPanel = uipanel(app.PathLossUIFigure);
            app.ParametrosgeneralesPanel.Title = 'Parametros generales';
            app.FrecuenciaMHzEditFieldLabel = uilabel(app.ParametrosgeneralesPanel);
            app.FrecuenciaMHzEditFieldLabel.Text = 'Frecuencia (MHz)';
            app.FrecuenciaMHzEditField = uieditfield(app.ParametrosgeneralesPanel, 'numeric');
            app.FrecuenciaMHzEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.FrecuenciaMHzEditField.Value = 900;
            app.DistanciaKmEditFieldLabel = uilabel(app.ParametrosgeneralesPanel);
            app.DistanciaKmEditFieldLabel.Text = 'Distancia (Km)';
            app.DistanciaKmEditField = uieditfield(app.ParametrosgeneralesPanel, 'numeric');
            app.DistanciaKmEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.DistanciaKmEditField.Value = 100;
            app.GtEditFieldLabel = uilabel(app.ParametrosgeneralesPanel);
            app.GtEditFieldLabel.Text = 'Gt';
            app.GtEditField = uieditfield(app.ParametrosgeneralesPanel, 'numeric');
            app.GtEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.GtEditField.Value = 1;
            app.GrEditFieldLabel = uilabel(app.ParametrosgeneralesPanel);
            app.GrEditFieldLabel.Text = 'Gr';
            app.GrEditField = uieditfield(app.ParametrosgeneralesPanel, 'numeric');
            app.GrEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.GrEditField.Value = 1;
            app.htmEditFieldLabel = uilabel(app.ParametrosgeneralesPanel);
            app.htmEditFieldLabel.Text = 'ht (m)';
            app.htmEditField = uieditfield(app.ParametrosgeneralesPanel, 'numeric');
            app.htmEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.htmEditField.Value = 50;
            app.hrmEditFieldLabel = uilabel(app.ParametrosgeneralesPanel);
            app.hrmEditFieldLabel.Text = 'hr (m)';
            app.hrmEditField = uieditfield(app.ParametrosgeneralesPanel, 'numeric');
            app.hrmEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.hrmEditField.Value = 2;
            app.ModulacionesPanel = uipanel(app.PathLossUIFigure);
            app.ModulacionesPanel.Title = 'Modulaciones';
            app.TipoDropDownLabel = uilabel(app.ModulacionesPanel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.ModulacionesPanel);
            app.TipoDropDown.Items = {'BPSK', 'QPSK', '8PSK', '16QAM', '32QAM', '64QAM', '128QAM', '256QAM'};
            app.TipoDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.TipoDropDown.Value = 'BPSK';
            % Show the figure after all components are created
            app.PathLossUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ejemplo2_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PathLossUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PathLossUIFigure)
        end
    end
end