classdef Aplicacion_video5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TipodeModulacionDropDownLabel   matlab.ui.control.Label
        TipodeModulacionDropDown        matlab.ui.control.DropDown
        ChannelEstimationDropDownLabel  matlab.ui.control.Label
        ChannelEstimationDropDown       matlab.ui.control.DropDown
        SNREditFieldLabel               matlab.ui.control.Label
        SNREditField                    matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.EditField
        SNRmeasuredLabel                matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        BERLabel                        matlab.ui.control.Label
        TipodecanalDropDownLabel        matlab.ui.control.Label
        TipodecanalDropDown             matlab.ui.control.DropDown
        DopplerCheckBox                 matlab.ui.control.CheckBox
        InterferenciaCheckBox           matlab.ui.control.CheckBox
        MobileRangemSliderLabel         matlab.ui.control.Label
        MobileRangemSlider              matlab.ui.control.Slider
        MobileAngleSlider_2Label        matlab.ui.control.Label
        MobileAngleSlider               matlab.ui.control.Slider
        InterfRangemSliderLabel         matlab.ui.control.Label
        InterfRangemSlider              matlab.ui.control.Slider
        InterfAngleSlider_2Label        matlab.ui.control.Label
        InterfAngleSlider               matlab.ui.control.Slider
        NumberofAntennasEditFieldLabel  matlab.ui.control.Label
        NumberofAntennasEditField       matlab.ui.control.NumericEditField
        KeyholeCheckBox                 matlab.ui.control.CheckBox
        SteeringAngleSliderLabel        matlab.ui.control.Label
        SteeringAngleSlider             matlab.ui.control.Slider
        MultiPathCheckBox               matlab.ui.control.CheckBox
        EditField_3                     matlab.ui.control.EditField
        SINRmeasuredLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
            nfft; n_fft; n_cpe; video;
            controlDoppler;controlInter;controlKey;controlMultiPath;
            finApp;
            PolarAxes % Polar axes
            fCentral;cLight;
            scanAz;
    end
    
    methods (Access = private)
        
        function [x_s_noise, noise] = addNoise(app,x_s)
            % Calculate data power
            data_pwr = mean(abs(x_s.^2));
            
            % Add noise to the channel
            noise_pwr = data_pwr/10^(app.SNREditField.Value/10);
            %
            noise = normrnd(0,sqrt(noise_pwr/3),size(x_s))+normrnd(0,sqrt(noise_pwr/3),size(x_s))*1i;
            x_s_noise = x_s + noise;
        end
        
        function x_s_noise_fading = channel(app,x_s_noise,w)
            
            chanSRate = 1000;
            CyclicPrefixLength = app.n_cpe;
            DelaySpread = CyclicPrefixLength - 1;
            
            if app.controlDoppler == 1 
                DopplerHz = 2;
            else
                DopplerHz = 0;
            end
            
            if app.controlMultiPath == 1 
                numPaths = 1000;
                PathDelays = floor(linspace(0,DelaySpread,numPaths))*(1/chanSRate);
                PathGains  = zeros(size(PathDelays));
                for n=2:numPaths
                    PathGains(n) = PathGains(n-1)-abs(randn);
                end
            else
                numPaths = 1;
                PathDelays = floor(linspace(0,DelaySpread,numPaths))*(1/chanSRate);
                PathGains  = zeros(size(PathDelays));
                for n=2:numPaths
                    PathGains(n) = PathGains(n-1)-abs(randn);
                end
            end
            
            if strcmp(app.TipodecanalDropDown.Value, 'MIMO')
                app.KeyholeCheckBox.Enable = 'on';
                if app.controlKey == 1
                    app.NumberofAntennasEditField.Enable = 'off';
                    chanObj = comm.MIMOChannel('SampleRate', chanSRate,'MaximumDopplerShift', DopplerHz, ...
                    'PathDelays', PathDelays,'AveragePathGains', PathGains,...
                    'SpatialCorrelationSpecification', 'None',...
                    'NumTransmitAntennas',1,'NumReceiveAntennas',1);
                    x_s_noise = repmat(x_s_noise,[1,1]); % Array de length(x_s_noise) x NumeroDeAntenas
                    for n=1:1
                        x_s_noise(:,n) = w(n)*x_s_noise(:,n);
                    end
                    x_s_noise_fading = step(chanObj, x_s_noise);
                    x_s_noise_fading = sum(x_s_noise_fading,2);
                else
                    app.NumberofAntennasEditField.Enable = 'on';
                    chanObj = comm.MIMOChannel('SampleRate', chanSRate,'MaximumDopplerShift', DopplerHz, ...
                    'PathDelays', PathDelays,'AveragePathGains', PathGains,...
                    'SpatialCorrelationSpecification', 'None',...
                    'NumTransmitAntennas',app.NumberofAntennasEditField.Value,'NumReceiveAntennas',app.NumberofAntennasEditField.Value);
                    x_s_noise = repmat(x_s_noise,[1,app.NumberofAntennasEditField.Value]); % Array de length(x_s_noise) x NumeroDeAntenas
                    for n=1:app.NumberofAntennasEditField.Value
                        x_s_noise(:,n) = w(n)*x_s_noise(:,n);
                    end
                    x_s_noise_fading = step(chanObj, x_s_noise);
                    x_s_noise_fading = sum(x_s_noise_fading,2);
                end
            end
            
            if strcmp(app.TipodecanalDropDown.Value, 'Rician')
                app.NumberofAntennasEditField.Enable = 'off';
                app.KeyholeCheckBox.Enable = 'off';
                ricianchan = comm.RicianChannel('SampleRate',chanSRate,'MaximumDopplerShift',DopplerHz,...
                    'PathDelays',PathDelays,'AveragePathGains',PathGains,...
                    'KFactor',10,'PathGainsOutputPort', true,'NormalizePathGains', true);
                %x_s_noise_fading = step(ricianchan, x_s_noise);
                x_s_noise_fading = ricianchan(x_s_noise);
            end
            
            if strcmp(app.TipodecanalDropDown.Value, 'Rayleigh')
                app.NumberofAntennasEditField.Enable = 'off';
                app.KeyholeCheckBox.Enable = 'off';
                rayleighchan = comm.RayleighChannel('SampleRate',chanSRate,'MaximumDopplerShift',DopplerHz,...
                    'PathDelays',PathDelays,'AveragePathGains',PathGains,...
                    'PathGainsOutputPort', true,'NormalizePathGains', true);
                x_s_noise_fading = step(rayleighchan, x_s_noise);
            end
        end
        
        function  main(app)
            sz = get(0, 'ScreenSize');
            vidOrigLocation = round([0.7, 0.1] .* sz(3:4));
            vidOrigSize = 2*[160 120];
            hVideoOutOrig = vision.DeployableVideoPlayer('Location',vidOrigLocation,'Size', 'Custom', 'CustomSize', vidOrigSize);
            
            sz2 = repmat(sz(3:4), 1,2);
            constPlotPosition = round([0.7, 0.50, 0.2381, 0.38] .* sz2);
            hC = comm.ConstellationDiagram('SamplesPerSymbol', 1,'ReferenceConstellation', [], 'ColorFading',true,'Position', constPlotPosition);
            
            lastSize=5760*4;
            
            while (app.finApp)
                [X_hat, snr_meas, rec_im, ber, resp, SINR] = frameThroughLink(app);
                X_hat = X_hat(1:100:end);
                
                if lastSize ~= numel(X_hat)
                    release(hC);
                end
                lastSize=numel(X_hat);
                step(hVideoOutOrig, rec_im);
                step(hC, X_hat);
                
                app.EditField.Value = num2str(snr_meas);
                app.EditField_2.Value = num2str(ber);
                app.EditField_3.Value = num2str(SINR);
                plotArray(app,resp);
            end
          delete(app);
        end
        
        
        function [cons_sym_id,sym_rem] = bitStreamTosymbols(app, im_bin)
            
            if strcmp(app.TipodeModulacionDropDown.Value, 'BPSK')
                mod_order = 1;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, 'QPSK')
                mod_order = 2;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '8PSK')
                mod_order = 3;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '16QAM')
                mod_order = 4;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '32QAM')
                mod_order = 5;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '64QAM')
                mod_order = 6;
            end
                      
            % 1. parse binary stream into mod_order bit symbols
            % 2. pads input signal to appropriate length
            sym_rem = mod(mod_order-mod(length(im_bin),mod_order),mod_order);
            padding = repmat('0',sym_rem,1);
            im_bin_padded = [im_bin;padding];
            cons_data = reshape(im_bin_padded,mod_order,length(im_bin_padded)/mod_order)';
            cons_sym_id = bin2dec(cons_data);    
        end
        
        function X_hat_blocks = estimateChannels(app, X_hat_blocks, X_blocks)
            switch(app.ChannelEstimationDropDown.Value)
               case 'none'
               case 'LS'
               G = X_hat_blocks(:,1)./X_blocks(:,1);
               X_hat_blocks = X_hat_blocks./repmat(G,1,size(X_hat_blocks,2));
            end
        end
        
        function X_hat_blocks = FFT(app,x_s_noise_fading)
            % Remove cyclic prefix extension and shift from serial to parallel
            x_p = reshape(x_s_noise_fading,app.nfft+app.n_cpe,length(x_s_noise_fading)/(app.nfft+app.n_cpe));
            x_p_cpr = x_p(app.n_cpe+1:end,:);
        
            % Move to frequency domain
            X_hat_blocks = fft(x_p_cpr);
        end
        
        function [im, im_bin] = framesToBinary(app)
            %im = readFrame(app.video); 
            I = step(app.video);
            im = im2uint8(I);
            im_bin = dec2bin(im(:))';
            im_bin = im_bin(:);
            
        end
        
        function [X_hat, snr_meas, rec_im, ber, resp, SINR] = frameThroughLink(app)
            % Read the image and convert it into binary format.
            [im, im_bin] = framesToBinary(app);
                 
            % Binary stream to symbols    
            [cons_sym_id,sym_rem] = bitStreamTosymbols(app, im_bin);
            
            % symbol modulation
            [X,symbol_book] = symbolModulation(app, cons_sym_id);
          
            % Use IFFT to move to time domain
            [x_s, X_blocks, fft_rem] = IFFT(app,X);
            
            % Add AWGN
            [x_s_noise, noise] = addNoise(app,x_s);
            
            [weight, resp] = transmitThroughArray(app);
            
            % Apply fading channel 
            x_s_noise_fading = channel(app,x_s_noise,weight);
            
            [x_s_noise_fading_interf,PotenciaIntermW] = channelInterf(app,x_s_noise_fading);
            
            % Use FFT to move to frequency domain
            X_hat_blocks = FFT(app,x_s_noise_fading_interf);
            
            % Estimate channels
            X_hat_blocks = estimateChannels(app, X_hat_blocks, X_blocks);
            
            % Symbol demodulation
            [rec_im_bin, ber, X_hat] = symbolDemodulation(app,X_hat_blocks,fft_rem, symbol_book, im_bin, sym_rem);
            
            % recover image
            rec_im = recoverImage(app,rec_im_bin, im);
            
            snr_meas = 10*log10(mean(abs(x_s_noise_fading_interf.^2))/mean(abs(noise.^2))); %señal tx + ruido + canal + interf/ ruido
            if PotenciaIntermW ~= 0
                SINR = snr_meas - 10*log10(PotenciaIntermW);
            else
                SINR = snr_meas;
            end
        end
        
        function [x_s, X_blocks, fft_rem] = IFFT(app,X)
            % pad input signal to appropriate length
            fft_rem = mod(app.n_fft-mod(length(X),app.n_fft),app.n_fft);
            X_padded = [X;zeros(fft_rem,1)];
            X_blocks = reshape(X_padded,app.nfft,length(X_padded)/app.nfft);
            x = ifft(X_blocks);
        
            %Add cyclic prefix entension and shift from parallel to serial
            x_cpe = [x(end-app.n_cpe+1:end,:);x];
            x_s   = x_cpe(:);
        end
        
        function rec_im = recoverImage(~, rec_im_bin, im)
            rec_im = reshape(rec_im_bin,8,numel(rec_im_bin)/8);
            rec_im = uint8(bin2dec(rec_im'));
            rec_im = reshape(rec_im,size(im)); 
        end
        
        function [rec_im_bin, ber, X_hat] = symbolDemodulation(~,X_hat_blocks,fft_rem, symbol_book, im_bin, sym_rem)
            % remove fft padding 
            X_hat = X_hat_blocks(:);
            X_hat = X_hat(1:end-fft_rem);
            
            %Recover data from modulated symbols
            A=[real(symbol_book) imag(symbol_book)];
            if (size(A,2)>2)
                A=[real(symbol_book)' imag(symbol_book)'];
            end
            rec_syms = knnsearch(A,[real(X_hat) imag(X_hat)])-1;
            
            %Parse to binary stream to remove symbol padding
            rec_syms_cons = dec2bin(rec_syms);
            rec_im_bin = reshape(rec_syms_cons',numel(rec_syms_cons),1);
            rec_im_bin = rec_im_bin(1:end-sym_rem);
            ber = sum(abs(rec_im_bin-im_bin))/length(im_bin);   
        end
        
        function [X,symbol_book] = symbolModulation(app,cons_sym_id)
            
            if strcmp(app.TipodeModulacionDropDown.Value, 'BPSK')
                mod_order = 1;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, 'QPSK')
                mod_order = 2;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '8PSK')
                mod_order = 3;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '16QAM')
                mod_order = 4;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '32QAM')
                mod_order = 5;
            end
            if strcmp(app.TipodeModulacionDropDown.Value, '64QAM')
                mod_order = 6;
            end
            
            % BPSK
            if mod_order == 1
                mod_ind = 2^(mod_order-1);
                n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                in_phase = cos(n);
                quadrature = sin(n);
                symbol_book = (in_phase + quadrature*1i);
            end
            
            % Phase shift keying about unit circle 
            if mod_order == 2 || mod_order == 3
                mod_ind = 2^(mod_order-1);
                n = 0:pi/mod_ind:2*pi-pi/mod_ind;
                in_phase = cos(n+pi/4);
                quadrature = sin(n+pi/4);
                symbol_book = (in_phase + quadrature*1i);
            end
            
            %16QAM, 64QAM
            if mod_order == 4 || mod_order == 6
                mod_ind = sqrt(2^mod_order);
                in_phase = repmat(linspace(-1,1,mod_ind),mod_ind,1);
                quadrature = repmat(linspace(-1,1,mod_ind)',1,mod_ind);
                symbol_book = (in_phase(:) + quadrature(:)*1i);
            end
            
            %32QAM - Generates 6x6 constellation and removes corners
            if mod_order == 5
                mod_ind = 6;
                in_phase = repmat(linspace(-1,1,mod_ind),mod_ind,1);
                quadrature = repmat(linspace(-1,1,mod_ind)',1,mod_ind);
                symbol_book = (in_phase(:) + quadrature(:)*1i);
                symbol_book = symbol_book([2:5 7:30 32:35]); %corners are removed
            end
            
            %modulate data according to the symbol_book
            X = symbol_book(cons_sym_id+1);
        end
        
        
        function [x_s_noise_fading_interf,PotenciaIntermW] = channelInterf(app,x_s_noise_fading)
            
            PotenciadBm = 20;
            PotenciamW = 10^(PotenciadBm/10);
            G = 10;
            tgtLoss = ((4*pi) * app.MobileRangemSlider.Value * app.fCentral) / ((app.cLight) * G); % Calculate path loss (target)
            TxGain =  PotenciamW / tgtLoss;
               
            if app.controlInter == 1
                app.SINRmeasuredLabel.Enable = 'on';
                app.EditField_3.Enable = 'on';
                hFreqShift = comm.PhaseFrequencyOffset('FrequencyOffsetSource', 'Input port','SampleRate',1);
                PathLossConstant = 20*log10(4*pi * app.fCentral / (app.cLight* G)); % Valor del path loss
                intMobileAngle = deg2rad(app.MobileAngleSlider.Value - app.InterfAngleSlider.Value);
                intMobileDistSqr=app.InterfRangemSlider.Value^2+app.MobileRangemSlider.Value^2-2*app.MobileRangemSlider.Value*app.InterfRangemSlider.Value*cos(intMobileAngle);
                intLoss = PathLossConstant + 10*log10(intMobileDistSqr);% Calculate path loss (interferer)
                
                PotenciaInterfdBm = 12;
                PotenciaIntermW = 10^(PotenciaInterfdBm/10);
                IntGain =  PotenciaIntermW / (10^(intLoss/20));
                
                numBits = size(x_s_noise_fading,1);
                intSymbols = wgn(numBits,1,1,'linear','complex');
                release(hFreqShift)
                intShifted = step(hFreqShift, intSymbols, 0.0);
                
                Signal1 = TxGain * x_s_noise_fading;
                Signal2 = IntGain * intShifted;
                RxSignal = [Signal1 , Signal2];
                x_s_noise_fading_interf =  sum(RxSignal,2);
                %SINR = 20*log10(TxGain) - 20*log10(IntGain);
            else
                x_s_noise_fading_interf = TxGain * x_s_noise_fading;
                PotenciaIntermW = 0;
                app.SINRmeasuredLabel.Enable = 'off';
                app.EditField_3.Enable = 'off';
            end
        end

        function plotArray(app,arrayResponse)
            max_rho = 5e2;
            
            if app.controlInter == 1
                polarPlot = polarplot(app.PolarAxes, deg2rad(app.MobileAngleSlider.Value), app.MobileRangemSlider.Value, 'sg', ...
                    deg2rad(app.InterfAngleSlider.Value), app.InterfRangemSlider.Value, 'or', ...
                    deg2rad(app.scanAz(:)), abs(arrayResponse(:))*max_rho/2, '-m');
                set(polarPlot(1), 'MarkerFaceColor', 'g');
                set(polarPlot(2), 'MarkerFaceColor', 'r');
            else
                polarPlot = polarplot(app.PolarAxes, deg2rad(app.MobileAngleSlider.Value), app.MobileRangemSlider.Value, 'sg',...
                    deg2rad(app.scanAz(:)), abs(arrayResponse(:))*max_rho/2, '-m');
                set(polarPlot(1), 'MarkerFaceColor', 'g');   
            end
        end
        
        function [weight, resp] = transmitThroughArray(app)
            ant = phased.IsotropicAntennaElement('FrequencyRange', app.fCentral + [-1e6 1e6],'BackBaffled', true);

            if app.NumberofAntennasEditField.Value == 1
                weight = 1+0j;
                resp = ones(size(app.scanAz));
            else
                lambda = app.cLight/app.fCentral;
                
                hB = phased.ULA(app.NumberofAntennasEditField.Value,'ElementSpacing', 0.5*lambda,'Element', ant);
                
                hR = phased.ArrayResponse('SensorArray',hB,'WeightsInputPort',true);
                
                hstv = phased.SteeringVector('SensorArray',hB,'PropagationSpeed',app.cLight);
                
                wT = step(hstv,app.fCentral,[app.SteeringAngleSlider.Value;0]);
                
                wR = step(hstv,app.fCentral,[-app.MobileAngleSlider.Value;0]);
                
                weight = wT .*wR;
                
                % Get collection of [az;el] pairs (ang(2,1) is constant 0 el)
                respAng = [app.scanAz;ones(size(app.scanAz))*0.0];
                % Get array response through hR6
                resp = step(hR,app.fCentral,respAng,wT);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.nfft = 64; 
            app.n_fft = 64; 
            app.n_cpe = 16; 
            app.video = vision.VideoFileReader('uavvideo.avi');          
            app.finApp = true;
            
            app.controlInter = 0;
            app.controlDoppler = 0;
            app.controlKey = 0;
            app.controlMultiPath = 0;
            
            app.fCentral = 700e6;
            app.cLight = 3e8;
            
            app.PolarAxes = polaraxes(app.UIFigure);
            app.PolarAxes.Units = 'pixels';
            app.PolarAxes.Position = [120 100 230 230];
            
            app.NumberofAntennasEditField.Enable = 'off';
            app.KeyholeCheckBox.Enable = 'off';
            app.SINRmeasuredLabel.Enable = 'off';
            app.EditField_3.Enable = 'off';
            
            app.scanAz = -180:180;
            
            main(app);
            
        end

        % Value changed function: ChannelEstimationDropDown, 
        % EditField, EditField_2, EditField_3, InterfAngleSlider, 
        % InterfRangemSlider, MobileAngleSlider, 
        % MobileRangemSlider, NumberofAntennasEditField, 
        % SNREditField, SteeringAngleSlider, 
        % TipodeModulacionDropDown, TipodecanalDropDown
        function ComponentValueChanged(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.finApp = false;
        end

        % Value changed function: DopplerCheckBox
        function DopplerCheckBoxValueChanged(app, event)
            value = app.DopplerCheckBox.Value;
            if value
                app.controlDoppler = 1;
            else
                app.controlDoppler = 0;
            end
        end

        % Value changed function: InterferenciaCheckBox
        function InterferenciaCheckBoxValueChanged(app, event)
            value = app.InterferenciaCheckBox.Value;
            if value
                app.controlInter = 1;
            else
                app.controlInter = 0;
            end
        end

        % Value changed function: KeyholeCheckBox
        function KeyholeCheckBoxValueChanged(app, event)
            value = app.KeyholeCheckBox.Value;
            if value
                app.controlKey = 1;
            else
                app.controlKey = 0;
            end
        end

        % Value changed function: MultiPathCheckBox
        function MultiPathCheckBoxValueChanged(app, event)
            value = app.MultiPathCheckBox.Value;
            if value
                app.controlMultiPath = 1;
            else
                app.controlMultiPath = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TipodeModulacionDropDownLabel = uilabel(app.UIFigure);
            app.TipodeModulacionDropDownLabel.Text = 'Tipo de Modulacion';
            app.TipodeModulacionDropDown = uidropdown(app.UIFigure);
            app.TipodeModulacionDropDown.Items = {'BPSK', 'QPSK', '16QAM', '64QAM'};
            app.TipodeModulacionDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.TipodeModulacionDropDown.Value = '16QAM';
            app.ChannelEstimationDropDownLabel = uilabel(app.UIFigure);
            app.ChannelEstimationDropDownLabel.Text = 'Channel Estimation';
            app.ChannelEstimationDropDown = uidropdown(app.UIFigure);
            app.ChannelEstimationDropDown.Items = {'LS', 'none'};
            app.ChannelEstimationDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.ChannelEstimationDropDown.Value = 'LS';
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.SNREditField.Value = 20;
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.SNRmeasuredLabel = uilabel(app.UIFigure);
            app.SNRmeasuredLabel.Text = 'SNR measured';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.BERLabel = uilabel(app.UIFigure);
            app.BERLabel.Text = 'BER';
            app.TipodecanalDropDownLabel = uilabel(app.UIFigure);
            app.TipodecanalDropDownLabel.Text = 'Tipo de canal';
            app.TipodecanalDropDown = uidropdown(app.UIFigure);
            app.TipodecanalDropDown.Items = {'MIMO', 'Rician', 'Rayleigh'};
            app.TipodecanalDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.TipodecanalDropDown.Value = 'Rician';
            app.DopplerCheckBox = uicheckbox(app.UIFigure);
            app.DopplerCheckBox.ValueChangedFcn = createCallbackFcn(app, @DopplerCheckBoxValueChanged, true);
            app.DopplerCheckBox.Text = 'Doppler';
            app.InterferenciaCheckBox = uicheckbox(app.UIFigure);
            app.InterferenciaCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterferenciaCheckBoxValueChanged, true);
            app.InterferenciaCheckBox.Text = 'Interferencia';
            app.MobileRangemSliderLabel = uilabel(app.UIFigure);
            app.MobileRangemSliderLabel.Text = 'Mobile Range (m)';
            app.MobileRangemSlider = uislider(app.UIFigure);
            app.MobileRangemSlider.Limits = [50 500];
            app.MobileRangemSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.MobileRangemSlider.Value = 50;
            app.MobileAngleSlider_2Label = uilabel(app.UIFigure);
            app.MobileAngleSlider_2Label.Text = 'Mobile Angle';
            app.MobileAngleSlider = uislider(app.UIFigure);
            app.MobileAngleSlider.Limits = [-90 90];
            app.MobileAngleSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.MobileAngleSlider.Value = -30;
            app.InterfRangemSliderLabel = uilabel(app.UIFigure);
            app.InterfRangemSliderLabel.Text = 'Interf Range (m)';
            app.InterfRangemSlider = uislider(app.UIFigure);
            app.InterfRangemSlider.Limits = [550 1000];
            app.InterfRangemSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.InterfRangemSlider.Value = 550;
            app.InterfAngleSlider_2Label = uilabel(app.UIFigure);
            app.InterfAngleSlider_2Label.Text = 'Interf Angle';
            app.InterfAngleSlider = uislider(app.UIFigure);
            app.InterfAngleSlider.Limits = [-90 90];
            app.InterfAngleSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.InterfAngleSlider.Value = 20;
            app.NumberofAntennasEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAntennasEditFieldLabel.Text = 'Number of Antennas';
            app.NumberofAntennasEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofAntennasEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.NumberofAntennasEditField.Value = 4;
            app.KeyholeCheckBox = uicheckbox(app.UIFigure);
            app.KeyholeCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeyholeCheckBoxValueChanged, true);
            app.KeyholeCheckBox.Text = 'Keyhole';
            app.SteeringAngleSliderLabel = uilabel(app.UIFigure);
            app.SteeringAngleSliderLabel.Text = 'SteeringAngle';
            app.SteeringAngleSlider = uislider(app.UIFigure);
            app.SteeringAngleSlider.Limits = [-90 90];
            app.SteeringAngleSlider.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.SteeringAngleSlider.Value = -30;
            app.MultiPathCheckBox = uicheckbox(app.UIFigure);
            app.MultiPathCheckBox.ValueChangedFcn = createCallbackFcn(app, @MultiPathCheckBoxValueChanged, true);
            app.MultiPathCheckBox.Text = 'Multi Path';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.SINRmeasuredLabel = uilabel(app.UIFigure);
            app.SINRmeasuredLabel.Text = 'SINR measured';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplicacion_video5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end